classdef climatewedges < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        dataInputTab                    matlab.ui.container.TabGroup
        Files                           matlab.ui.container.Tab
        TextArea                        matlab.ui.control.TextArea
        TextArea_2                      matlab.ui.control.TextArea
        WedgesTextFileEditFieldLabel    matlab.ui.control.Label
        ConstraintsTextFile             matlab.ui.control.EditField
        StrategyTextFile                matlab.ui.control.EditField
        WedgesTextFile                  matlab.ui.control.EditField
        ConstraintsTextFileLabel        matlab.ui.control.Label
        StrategyTextFileEditFieldLabel  matlab.ui.control.Label
        ErrorText                       matlab.ui.control.TextArea
        WedgesTextLamp                  matlab.ui.control.Lamp
        ConstraintsTextLamp             matlab.ui.control.Lamp
        StrategyTextLamp                matlab.ui.control.Lamp
        WedgesByTable                   matlab.ui.container.Tab
        ConstraintsbyTable              matlab.ui.container.Tab
    end


    properties (Access = private)
        wedgeHeader %cell of each name of wedge struct field
    end

    properties (Access = public)
        wedges % struct full of wedges
        constraints % struct with portfolio constraints
        strat %struct with all strategy info
    end

    methods (Access = public)
    
        function [] = readWedges(app, file_name)
            %readWedges get wedges info into struct
            %   Each wedge has it's ID as it's position in struct, as well as ID
            %   each element of header is in field
            fid = fopen(file_name); 
            line = fgetl(fid);
            header = strsplit(line, ';'); %split at semicolons
            app.wedges = struct(); %make struct
            for indField = 1:numel(header)
                app.wedges.(header{indField}) = NaN; %make all the fields w/ dummy val
            end
            line = fgetl(fid);
            while(~isnumeric(line))
                parts = strsplit(line, ';');
                for indF = 1:numel(header)
                    if(~isnan(str2double(parts{indF})))
                        parts{indF} = str2double(parts{indF}); %conver to doubles
                    end %if
                    app.wedges(parts{1}).(header{indF})= parts{indF};
                        %add each part into the struct, ID is also it's position
        
                end %for loop
                line = fgetl(fid);
            end% while loop
            app.wedgeHeader = header; %assign to global
        end
        
        function [] = readConstraints(app, file_name)
            %readConstraints Put 'em in a struct
            %   each sector is a field, with limits as double value
            fid = fopen(file_name);%open file
            line = fgetl(fid);
            header = strsplit(line); %split at semicolons
            app.constraints = struct(); %make struct
            line = fgetl(fid);
            while(~isnumeric(line))
                parts = strsplit(line);
                parts{2} = str2double(parts{2}); %conver to doubles
                app.constraints.(parts{1})= parts{2};
                    %add each part into the struct, ID is also it's position
                line = fgetl(fid);
            end% while loop
        end
        
        function [] = readStrat(app, file_name)
            fid = fopen(file_name);%open file
            line = fgetl(fid);
            header = strsplit(line); %split at semicolons
            app.strat = struct(); %make struct
            for indField = 1:numel(header)
                app.strat.(header{indField}) = NaN; %make all the fields w/ dummy val
            end
            line = fgetl(fid);
            indSpot = 1; %increment through each spot
            while(~isnumeric(line))
                parts = strsplit(line);
                for indF = 1:numel(header)
                    if(~isnan(str2double(parts{indF})))
                        parts{indF} = str2double(parts{indF}); %conver to doubles
                    end %if
                    app.strat(indSpot).(header{indF})= parts{indF};
                        %add each part into the struct, ID is also it's position
        
                end %for loop
                line = fgetl(fid);
                indSpot = indSpot + 1; %next spot
            end% while loop
        end
    end

    methods (Access = private)
    
        function [] = toggleInputFields(app)
            %toggleInputFields Startup cleanup
            %   See if structs are filled, and based on that
            %   disable input fields to keep it clean on start screen
            if(isfield(app.wedges, 'ID')) %wedges is filled
                app.WedgesTextFile.Enable = 'off';
                app.WedgesTextFile.Value = '';
                app.WedgesTextLamp.Color = 'green';
            end
            if(isfield(app.constraints, 'Electricity') || isfield(app.constraints, ...
                    'Transportation') || isfield(app.constraints, 'Fuel'))
                %one of the fields exist, so we can move from here
                app.ConstraintsTextFile.Enable = 'off';
                app.ConstraintsTextFile.Value = '';
                app.ConstraintsTextLamp.Color = 'green';
            end
            if(isfield(app.strat, 'ID')) %strat is filled
                app.StrategyTextFile.Enable = 'off';
                app.StrategyTextFile.Value = '';
                app.StrategyTextLamp.Color = 'green';
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            numFiles = size(varargin); %how many files? should be 1, 2, or 3
            if(numFiles > 0)%we have at least 1?
                readWedges(app,varargin{1}); %assign variables, assigns to prop inside
            end
            if(numFiles > 1) %we have at least 2
                readConstraints(app,varargin{2});
            end
            if(numFiles == 3) %we have three files
                app.strategy = readStrategy(app,varargin{3});
            end
            toggleInputFields(app);
            
            
            %Code to test if structs fillign right
%             tempCell = struct2cell(app.wedges);
%             cell(:,:) = tempCell(:,1,:);
%             app.UITable.Data = cell;
        end

        % Value changed function: ConstraintsTextFile
        function ConstraintsTextFileValueChanged(app, event)
            value = app.ConstraintsTextFile.Value;
            try
                readConstraints(app,value);
            catch
                app.ErrorText.Value = 'Error in Constraint Text File.  Please check that your file and directory are correct.';
            end
            toggleInputFields(app);
                
        end

        % Value changed function: StrategyTextFile
        function StrategyTextFileValueChanged(app, event)
            value = app.StrategyTextFile.Value;
            try
                readStrategy(app,value);
            catch
                app.ErrorText.Value = 'Error in Strategy Text File.  Please check that your file and directory are correct.';
            end
            toggleInputFields(app);
            
        end

        % Value changed function: WedgesTextFile
        function WedgesTextFileValueChanged(app, event)
            value = app.WedgesTextFile.Value;
            try
                readWedges(app,value);
            catch
                app.ErrorText.Value = 'Error in Wedges Text File.  Please check that your file and directory are correct.';
            end
            toggleInputFields(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.dataInputTab = uitabgroup(app.UIFigure);
            app.Files = uitab(app.dataInputTab);
            app.Files.Title = 'Files';
            app.TextArea = uitextarea(app.Files);
            app.TextArea.Value = {'Please input the wedges and constraint info.'};
            app.TextArea_2 = uitextarea(app.Files);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'You can enter the file names/filepaths here, or enter them manually into the tables on the other tabs.  If you have already entered data (through input argument or another method), it''s entry position will be greyed out.'};
            app.WedgesTextFileEditFieldLabel = uilabel(app.Files);
            app.WedgesTextFileEditFieldLabel.Text = 'Wedges Text File';
            app.ConstraintsTextFile = uieditfield(app.Files, 'text');
            app.ConstraintsTextFile.ValueChangedFcn = createCallbackFcn(app, @ConstraintsTextFileValueChanged, true);
            app.StrategyTextFile = uieditfield(app.Files, 'text');
            app.StrategyTextFile.ValueChangedFcn = createCallbackFcn(app, @StrategyTextFileValueChanged, true);
            app.WedgesTextFile = uieditfield(app.Files, 'text');
            app.WedgesTextFile.ValueChangedFcn = createCallbackFcn(app, @WedgesTextFileValueChanged, true);
            app.ConstraintsTextFileLabel = uilabel(app.Files);
            app.ConstraintsTextFileLabel.Text = 'Constraints Text File';
            app.StrategyTextFileEditFieldLabel = uilabel(app.Files);
            app.StrategyTextFileEditFieldLabel.Text = 'Strategy Text File';
            app.ErrorText = uitextarea(app.Files);
            app.ErrorText.Editable = 'off';
            app.WedgesTextLamp = uilamp(app.Files);
            app.ConstraintsTextLamp = uilamp(app.Files);
            app.StrategyTextLamp = uilamp(app.Files);
            app.WedgesByTable = uitab(app.dataInputTab);
            app.WedgesByTable.Title = 'Wedges by Table';
            app.ConstraintsbyTable = uitab(app.dataInputTab);
            app.ConstraintsbyTable.Title = 'Constraints by Table';
        end
    end

    methods (Access = public)

        % Construct app
        function app = climatewedges(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end